classdef Task2App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        SettlingtimeminutesLabel    matlab.ui.control.Label
        theta_2EditField_2          matlab.ui.control.NumericEditField
        theta_2EditField_2Label     matlab.ui.control.Label
        theta_1EditField_2          matlab.ui.control.NumericEditField
        theta_1EditField_2Label     matlab.ui.control.Label
        theta_2EditField            matlab.ui.control.NumericEditField
        theta_2EditFieldLabel       matlab.ui.control.Label
        theta_1EditField            matlab.ui.control.NumericEditField
        theta_1EditFieldLabel       matlab.ui.control.Label
        SteadystatevaluecircCLabel  matlab.ui.control.Label
        theta_aKEditField           matlab.ui.control.NumericEditField
        theta_aLabel                matlab.ui.control.Label
        R_3EditField                matlab.ui.control.NumericEditField
        R_3EditFieldLabel           matlab.ui.control.Label
        R_2EditField                matlab.ui.control.NumericEditField
        R_2EditFieldLabel           matlab.ui.control.Label
        R_1EditField                matlab.ui.control.NumericEditField
        R_1EditFieldLabel           matlab.ui.control.Label
        C_2EditField                matlab.ui.control.NumericEditField
        C_2EditFieldLabel           matlab.ui.control.Label
        ParametersLabel             matlab.ui.control.Label
        PlotButton                  matlab.ui.control.Button
        C_1EditField                matlab.ui.control.NumericEditField
        C_1Label                    matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            %Retrive model constants
            C_1 = app.C_1EditField.Value;   %First thermal capacitance in J/K
            C_2 = app.C_2EditField.Value;   %Second thermal capacitance in J/K
            R_1 = app.R_1EditField.Value;   %First thermal resistance in K/W
            R_2 = app.R_2EditField.Value;   %Second thermal resistance in K/W
            R_3 = app.R_3EditField.Value;   %Third thermal resistance in K/W
            theta_a = app.theta_aKEditField.Value; %Enviroment temperature in K (20 degrees Celsius)
            
            %Calculate sum in paralell of thermal resistances
            R_12 = ((R_1*R_2)/(R_1 + R_2)); %Sum of the first and second thermal
            %resistances in paralell
            R_23 = ((R_2*R_3)/(R_2 + R_3)); %Sum of the second and third thermal
            %resistances in paralell

            %Define equations for the constants of theta1
            A_1 = ( 1 / (C_1*C_2*R_2) );
            B_1 = 1;
            D_1 = ( (1 / (C_1*R_12)) + (1 / (C_2*R_23)));
            E_1 = ( ( ((R_2)^2) - (R_12*R_23) ) / ( C_1*C_2*R_12*R_23*((R_2)^2) ) );
            
            
            %Define equations for the constants of theta2
            A_2 = (1/C_1);
            B_2 = ( (1 / ( ((C_1)^2) * R_12 )) + (1 / (C_1*C_2*R_23)) );
            D_2 = (1 / ( ((C_1)^2)*C_2*R_12*R_23 ));
            E_2 = 1;
            F_2 = ( (2 / (C_1*R_12)) + (1 / (C_2*R_23)));
            G_2 = ( (1 / ( ((C_1)^2) * ((R_12)^2) )) + (1 / (C_1*C_2*R_12*R_23)) + ( (((R_2)^2) - (R_12*R_23)) / (C_1*C_2*R_12*R_23*((R_2)^2)) ) );
            H_2 = ( (((R_2)^2) - (R_12*R_23)) / ( ((C_1)^2)*C_2*((R_12)^2)*R_23*((R_2)^2)) );

            %Assign variables to base workspace
            assignin('base', "A_1", A_1);
            assignin('base', "B_1", B_1);
            assignin('base', "D_1", D_1);
            assignin('base', "E_1", E_1);

            assignin('base', "A_2", A_2);
            assignin('base', "B_2", B_2);
            assignin('base', "D_2", D_2);
            assignin('base', "E_2", E_2);
            assignin('base', "F_2", F_2);
            assignin('base', "G_2", G_2);
            assignin('base', "H_2", H_2);

            assignin('base', "R_12", R_12);
            assignin('base', "R_23", R_23);

            assignin('base', "theta_a", theta_a);

            %Run Simulink model
            simout = sim("task2sim.slx");
            hold off;
            %Plot graphs
            plot(app.UIAxes, (simout.theta_1.Time/60), simout.theta_1.Data);
            plot(app.UIAxes2, (simout.theta_2.Time/60), simout.theta_2.Data);
            plot(app.UIAxes3, (simout.input_heat_flow.Time/60), simout.input_heat_flow.Data);
            grid on;
            
            %calculate and display settling time and steady state value for
            %theta 1 and 2
            app.theta_1EditField.Value = simout.theta_1.Data(end);
            app.theta_2EditField.Value = simout.theta_2.Data(end);
            app.theta_1EditField_2.Value = stepinfo(simout.theta_1.Data, simout.theta_1.Time/60, simout.theta_1.Data(end), (theta_a - 273.15)).SettlingTime;
            app.theta_2EditField_2.Value = stepinfo(simout.theta_2.Data, simout.theta_2.Time/60, simout.theta_2.Data(end), (theta_a - 273.15)).SettlingTime;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {520, 520};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.C_1Label = uilabel(app.LeftPanel);
            app.C_1Label.Interpreter = 'tex';
            app.C_1Label.Text = 'C_{1}';
            app.C_1EditField = uieditfield(app.LeftPanel, 'numeric');
            app.C_1EditField.Limits = [1 Inf];
            app.C_1EditField.Value = 1;
            app.PlotButton = uibutton(app.LeftPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ParametersLabel = uilabel(app.LeftPanel);
            app.ParametersLabel.Text = 'Parameters';
            app.C_2EditFieldLabel = uilabel(app.LeftPanel);
            app.C_2EditFieldLabel.Interpreter = 'tex';
            app.C_2EditFieldLabel.Text = 'C_{2}';
            app.C_2EditField = uieditfield(app.LeftPanel, 'numeric');
            app.C_2EditField.Limits = [1 Inf];
            app.C_2EditField.Value = 1;
            app.R_1EditFieldLabel = uilabel(app.LeftPanel);
            app.R_1EditFieldLabel.Interpreter = 'tex';
            app.R_1EditFieldLabel.Text = 'R_{1}';
            app.R_1EditField = uieditfield(app.LeftPanel, 'numeric');
            app.R_1EditField.Limits = [1 Inf];
            app.R_1EditField.Value = 1;
            app.R_2EditFieldLabel = uilabel(app.LeftPanel);
            app.R_2EditFieldLabel.Interpreter = 'tex';
            app.R_2EditFieldLabel.Text = 'R_{2}';
            app.R_2EditField = uieditfield(app.LeftPanel, 'numeric');
            app.R_2EditField.Limits = [1 Inf];
            app.R_2EditField.Value = 1;
            app.R_3EditFieldLabel = uilabel(app.LeftPanel);
            app.R_3EditFieldLabel.Interpreter = 'tex';
            app.R_3EditFieldLabel.Text = 'R_{3}';
            app.R_3EditField = uieditfield(app.LeftPanel, 'numeric');
            app.R_3EditField.Limits = [1 Inf];
            app.R_3EditField.Value = 1;
            app.theta_aLabel = uilabel(app.LeftPanel);
            app.theta_aLabel.Interpreter = 'tex';
            app.theta_aLabel.Text = '\theta_{a}(K)';
            app.theta_aKEditField = uieditfield(app.LeftPanel, 'numeric');
            app.theta_aKEditField.Limits = [1 Inf];
            app.theta_aKEditField.ValueDisplayFormat = '%11.6g';
            app.theta_aKEditField.Value = 1;
            app.SteadystatevaluecircCLabel = uilabel(app.LeftPanel);
            app.SteadystatevaluecircCLabel.Interpreter = 'tex';
            app.SteadystatevaluecircCLabel.Text = {'Steady state'; 'value\circC'};
            app.theta_1EditFieldLabel = uilabel(app.LeftPanel);
            app.theta_1EditFieldLabel.Interpreter = 'tex';
            app.theta_1EditFieldLabel.Text = '\theta_{1}';
            app.theta_1EditField = uieditfield(app.LeftPanel, 'numeric');
            app.theta_1EditField.Limits = [0 Inf];
            app.theta_1EditField.ValueDisplayFormat = '%11.6g';
            app.theta_2EditFieldLabel = uilabel(app.LeftPanel);
            app.theta_2EditFieldLabel.Interpreter = 'tex';
            app.theta_2EditFieldLabel.Text = '\theta_{2}';
            app.theta_2EditField = uieditfield(app.LeftPanel, 'numeric');
            app.theta_2EditField.Limits = [0 Inf];
            app.theta_2EditField.ValueDisplayFormat = '%11.6g';
            app.theta_1EditField_2Label = uilabel(app.LeftPanel);
            app.theta_1EditField_2Label.Interpreter = 'tex';
            app.theta_1EditField_2Label.Text = '\theta_{1}';
            app.theta_1EditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.theta_1EditField_2.Limits = [0 Inf];
            app.theta_1EditField_2.ValueDisplayFormat = '%11.6g';
            app.theta_2EditField_2Label = uilabel(app.LeftPanel);
            app.theta_2EditField_2Label.Interpreter = 'tex';
            app.theta_2EditField_2Label.Text = '\theta_{2}';
            app.theta_2EditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.theta_2EditField_2.Limits = [0 Inf];
            app.theta_2EditField_2.ValueDisplayFormat = '%11.6g';
            app.SettlingtimeminutesLabel = uilabel(app.LeftPanel);
            app.SettlingtimeminutesLabel.Interpreter = 'tex';
            app.SettlingtimeminutesLabel.Text = 'Settling time (minutes)';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, '\theta_{1}')
            xlabel(app.UIAxes, 'Time(minutes)')
            ylabel(app.UIAxes, 'Temperature(\circC)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, '\theta_{2}')
            xlabel(app.UIAxes2, 'Time(minutes)')
            ylabel(app.UIAxes2, 'Temperature(\circC)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.RightPanel);
            title(app.UIAxes3, ' Input Heat Flow q_{1}(t)')
            xlabel(app.UIAxes3, 'Time(minutes)')
            ylabel(app.UIAxes3, 'q_{1}(J)')
            zlabel(app.UIAxes3, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Task2App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end